classdef Definitivo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ProblemadirectoeinversoTab   matlab.ui.container.Tab
        Velocidad                    matlab.ui.control.DropDown
        VelocidaddesimulacinDropDownLabel  matlab.ui.control.Label
        RepetirInv                   matlab.ui.control.Button
        RepetirDir                   matlab.ui.control.Button
        Orientacion                  matlab.ui.control.NumericEditField
        OrientacinLabel              matlab.ui.control.Label
        InfoDir                      matlab.ui.control.Image
        d3                           matlab.ui.control.NumericEditField
        d3EditFieldLabel             matlab.ui.control.Label
        Z                            matlab.ui.control.NumericEditField
        ZEditFieldLabel              matlab.ui.control.Label
        Y                            matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        X                            matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        Theta4                       matlab.ui.control.NumericEditField
        Theta4EditFieldLabel         matlab.ui.control.Label
        Theta2                       matlab.ui.control.NumericEditField
        Theta2Label                  matlab.ui.control.Label
        Theta1                       matlab.ui.control.NumericEditField
        Theta1EditFieldLabel         matlab.ui.control.Label
        InfoInv                      matlab.ui.control.Image
        InvBut                       matlab.ui.control.Button
        DirBut                       matlab.ui.control.Button
        ENDDEFFECTORLabel            matlab.ui.control.Label
        ARTICULACIONESLabel          matlab.ui.control.Label
        MatricesdetransformacinTab   matlab.ui.container.Tab
        ParmetrosdeDHPanel           matlab.ui.container.Panel
        dEditField_2Label            matlab.ui.control.Label
        dDH                          matlab.ui.control.NumericEditField
        aDH                          matlab.ui.control.NumericEditField
        aEditField_2Label            matlab.ui.control.Label
        alphaEditField_2Label        matlab.ui.control.Label
        ThetaDH                      matlab.ui.control.NumericEditField
        AlphaDH                      matlab.ui.control.NumericEditField
        thetaEditField_2Label        matlab.ui.control.Label
        SeleccinmatrizDropDown       matlab.ui.control.DropDown
        SeleccinmatrizDropDownLabel  matlab.ui.control.Label
        MatrizdetransformacinhomogeneaPanel  matlab.ui.container.Panel
        a44                          matlab.ui.control.NumericEditField
        a43                          matlab.ui.control.NumericEditField
        a42                          matlab.ui.control.NumericEditField
        a41                          matlab.ui.control.NumericEditField
        a34                          matlab.ui.control.NumericEditField
        a33                          matlab.ui.control.NumericEditField
        a32                          matlab.ui.control.NumericEditField
        a31                          matlab.ui.control.NumericEditField
        a24                          matlab.ui.control.NumericEditField
        a23                          matlab.ui.control.NumericEditField
        a22                          matlab.ui.control.NumericEditField
        a21                          matlab.ui.control.NumericEditField
        a14                          matlab.ui.control.NumericEditField
        a13                          matlab.ui.control.NumericEditField
        a12                          matlab.ui.control.NumericEditField
        a11                          matlab.ui.control.NumericEditField
        ClculodetrayectoriasTab      matlab.ui.container.Tab
        RepSim                       matlab.ui.control.Button
        OrLabel5                     matlab.ui.control.Label
        Orientacion5                 matlab.ui.control.NumericEditField
        OrLabel4                     matlab.ui.control.Label
        Orientacion4                 matlab.ui.control.NumericEditField
        OrLabel3                     matlab.ui.control.Label
        Orientacion3                 matlab.ui.control.NumericEditField
        OrLabel2                     matlab.ui.control.Label
        Orientacion2                 matlab.ui.control.NumericEditField
        OrLabel1                     matlab.ui.control.Label
        Orientacion1                 matlab.ui.control.NumericEditField
        Z5Label                      matlab.ui.control.Label
        Y5Label                      matlab.ui.control.Label
        X5Label                      matlab.ui.control.Label
        Z4Label                      matlab.ui.control.Label
        Y4Label                      matlab.ui.control.Label
        X4Label                      matlab.ui.control.Label
        Z3Label                      matlab.ui.control.Label
        Y3Label                      matlab.ui.control.Label
        X3Label                      matlab.ui.control.Label
        Z2Label                      matlab.ui.control.Label
        Y2Label                      matlab.ui.control.Label
        X2Label                      matlab.ui.control.Label
        Z1Label                      matlab.ui.control.Label
        Y1Label                      matlab.ui.control.Label
        X1Label                      matlab.ui.control.Label
        NumPtos                      matlab.ui.control.Spinner
        NmerodepuntosSpinnerLabel    matlab.ui.control.Label
        trayect                      matlab.ui.control.Button
        pto5                         matlab.ui.control.Button
        pto4                         matlab.ui.control.Button
        pto3                         matlab.ui.control.Button
        pto2                         matlab.ui.control.Button
        pto1                         matlab.ui.control.Button
        z5                           matlab.ui.control.NumericEditField
        y5                           matlab.ui.control.NumericEditField
        z4                           matlab.ui.control.NumericEditField
        y4                           matlab.ui.control.NumericEditField
        z3                           matlab.ui.control.NumericEditField
        y3                           matlab.ui.control.NumericEditField
        z2                           matlab.ui.control.NumericEditField
        y2                           matlab.ui.control.NumericEditField
        z1                           matlab.ui.control.NumericEditField
        y1                           matlab.ui.control.NumericEditField
        x5                           matlab.ui.control.NumericEditField
        x4                           matlab.ui.control.NumericEditField
        x3                           matlab.ui.control.NumericEditField
        x2                           matlab.ui.control.NumericEditField
        x1                           matlab.ui.control.NumericEditField
        Punto5Label                  matlab.ui.control.Label
        Punto4Label                  matlab.ui.control.Label
        Punto3Label                  matlab.ui.control.Label
        Punto2Label                  matlab.ui.control.Label
        Punto1Label                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        pos_in;
        trayecEndDef;
        point;
        trayecTotEndDef;
        points;
        n = 0; % número de simulaciones totales (dir+inv) -1
        m = 0; % número de simulaciones prob inverso -1
        l = 0 % número de simulaciones de trayectoria -1
        np; % nº de puntos para realizar la trayectoria
        p1 = 0; % para comprobar que el Pto1 es válido
        p2 = 0;
        p3 = 0;
        p4 = 0;
        p5 = 0;
        c = 0;
        VarDirRep;
        VarInvRep;
        PosInvRep;
        PosRep;
        d1 = 387;
        a1 = 325;
        a2 = 275;
        r;
        PointsRep; % Almacenar puntos de la trayectoria para poder repetir 
                   % la simulación
        vel;
        selecc;
        ProbDir;
        Th1Trayect;
        Th2Trayect;
        d3Trayect;
        Th4Trayect;


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                app.selecc = 0;    
                app.np =2;
                app.x3.Visible = 'off';
                app.y3.Visible = 'off';
                app.z3.Visible = 'off';
                app.x4.Visible = 'off';
                app.y4.Visible = 'off';
                app.z4.Visible = 'off';
                app.x5.Visible = 'off';
                app.y5.Visible = 'off';
                app.z5.Visible = 'off';
                app.Punto3Label.Visible = 'off';
                app.Punto4Label.Visible = 'off';
                app.Punto5Label.Visible = 'off';
                app.pto3.Visible = 'off';
                app.pto4.Visible = 'off';
                app.pto5.Visible = 'off';
                app.X3Label.Visible = 'off';
                app.Y3Label.Visible = 'off';
                app.Z3Label.Visible = 'off';
                app.X4Label.Visible = 'off';
                app.Y4Label.Visible = 'off';
                app.Z4Label.Visible = 'off';
                app.X5Label.Visible = 'off';
                app.Y5Label.Visible = 'off';
                app.Z5Label.Visible = 'off';
                app.Orientacion3.Visible = 'off';
                app.Orientacion4.Visible = 'off';
                app.Orientacion5.Visible = 'off';
                app.OrLabel3.Visible = 'off';
                app.OrLabel4.Visible = 'off';
                app.OrLabel5.Visible = 'off';


        end

        % Image clicked function: InfoDir
        function InfoDirImageClicked(app, event)
            msgbox(['Resolución del problema DIRECTO en un robot ' ...
                'Scara usando el Robotic Toolbox de Peter Corke que permite ' ...
                'crear un robot a partir de los parámetros de DH. Para ello, ' ...
                'introduzca los valores de las variables. ' ...
                'Recuerde: theta1 = [-157.5 -157.5] / theta2 = [-105 - 105]' ...
                ' / d3 <= 210'], 'Información','modal')
        end

        % Image clicked function: InfoInv
        function InfoInvImageClicked(app, event)
             msgbox(['Resolución del problema INVERSO en un robot ' ...
                'Scara usando el Robotic Toolbox de Peter Corke que permite ' ...
                'crear un robot a partir de los parámetros de DH. Para ello, ' ...
                'introduzca las coordenadas del punto deseado. ' ...
                'Recuerde: el robot tiene un espacio de trabajo limitado. ' ...
                'El propio programa le avisará en el caso de que el punto ' ...
                'no pertenezca al espacio de trabajo del robot'],'Información','modal')
        end

        % Button pushed function: DirBut
        function DirButButtonPushed(app, event)
% Función para resolver el problema DIRECTO en un robot Scara usando el 
% Robotic Toolbox de Peter Corke que permite crear un robot a partir de 
% los parámetros de DH.


% Cogemos los valores asignados por el usuario a las variables:

th_1=app.Theta1.Value; % los ángulos tienen que estar en radianes
th_2=app.Theta2.Value;
th_4=app.Theta4.Value;
d3=app.d3.Value;


% Antes de nada vamos a comprobar si los valores introducidos están dentro
% de los límites establecidos por el fabricante. Para ello, primero vamos a
% transformar los valores de los ángulos para poder determinar si están 
% dentro del rango permitido de valores.También compruebo que se ha 
% seleccionado la velocidad de simulación ya que de lo contrario no se 
% realizará la simulación.


if th_2 <-150 || th_2 > 150
    warndlg('Valor de theta2 fuera del rango permitido, debe de estar dentro de [-150 - 150] grados','Aviso','modal')
    app.ProbDir = 0;
elseif th_1 <-105 ||  th_1 > 105
    warndlg('Valor de theta1 fuera del rango permitido, debe de estar dentro de [-105 - 105] grados','Aviso','modal')
    app.ProbDir = 0;
elseif d3 > 210
    warndlg('Valor de d3 mayor de lo permitido tiene que ser <210', 'Aviso','modal')
    app.ProbDir = 0;
elseif th_4 < -360 || th_4 > 360
    warndlg('Valor de theta4 fuera del rango permitido, debe de estar dentro de [-360 - 360] grados','Aviso','modal')
    app.ProbDir = 0;
else 
app.ProbDir = 1; % solo cuando todos los valores introducidos son válidos 
% se podrán determinar las matrices de transforamción asociadas.

% Guardo los valores introducidos por el usuario en un vector para cuando
% se quiere repetir la simulación poder verificar que no se han
% modificado los datos.

app.VarDirRep = [ th_1, th_2, d3, th_4];   
  

% En el caso de que los valores esten dentro del rango permitido 
% procedemos a resolver el problema directo y a representar la trayectoria 
% del robot.

% Las variables del robot tienen que estar en radianes:
th1=th_1*pi/180; % los ángulos tienen que estar en radianes
th2=th_2*pi/180;
th4=th_4*pi/180;

% Creamos las articulaciones mediante la función Link. Esta
% función crea las articulaciones usando los paramétros de DH. Sólo tiene 3
% entradas ya que el parámetro que no se incluye lo asume como variable, y
% en función de si este parametro variable es theta o d, la articulación
% será revolute o prismatic. En el caso del par prismático hay que
% espicificar sus valores límite.


L1 = Link('d',app.d1,'a',app.a1,'alpha',0,'qlim',[-105*pi/180,105*pi/180]); %R
L2 = Link('d',0,'a',app.a2,'alpha',pi,'qlim',[-157,5*pi/180,157,5*pi/180]);  %R
L3 = Link('theta',0,'a',0,'alpha',0,'qlim',[0,210]); %P
L4 = Link('d',0,'a',0,'alpha',0); %R no tiene limitación de giro

% Armamos el robot con la función SerialLink que crea la cadena cinemática.

scara = SerialLink([L1,L2,L3,L4],'name','Scara');

% Resolvemos el problema directo según los valores asignados a nuestras
% variables, es decir, calculamos la posición (x,y,z) del EndDeffector.

x = app.a1*cos(th1) + app.a2*cos(th1+th2);
y = app.a1*sin(th1) + app.a2*sin(th1+th2);
z = app.d1 - d3;

% Devolvemos a las casillas X,Y,Z el valor de las coordenas:

           app.X.Value = round(x,3); % redonde a 3 cifras decimales
           app.Y.Value = round(y,3);
           app.Z.Value = round(z,3);
           app.Orientacion.Value = app.Theta4.Value;

% Para poder representar la trayectoria realizada por el robot hasta llegar
% a la posición final, hacemos lo siguiente:

% Definimos una posición de partida del robot pos_offset(th1,th2,d3,th4)
% que se corresponde con la posición de reposo:

pos_offset = [0 0 0 0];

% En el caso de que el robot parta de otra posición ya definida (este sería
% el caso de varias simulaciones) su posición inicial será esa, en caso
% contrario la posición inicial se corresponderá con la pos_offset. Para
% comprobar si parte de una posición ya definida tenemos que comprobar si
% la propiedad pos_initial tiene algún valor almacenado:


if  app.n > 0 || app.m > 0  || app.l > 0 % en el caso de que no exista pos inicial definida
    pos_initial = app.pos_in;
else 
    pos_initial = pos_offset;
end

app.PosRep = pos_initial;

% DETERMINACIÓN DEL PASO PARA LA SIMULACIÓN
% En función de la diferencia entre los valores introducidos (distancia) se
% representarán más o menos puntos de precisión. De esta manera, se
% conseguirá que todas las simulaciones tengan una precisión similar.

difTh1 = abs(pos_initial(1)-th1); % en radianes
difTh2 = abs(pos_initial(2)-th2);
difD3 = abs(pos_initial(3)-d3); % en milimetros

% No considero la diferencia en en theta 4 porque no supone ningún cambio
% en la trayectoria realizada por el end deffector, simplemente modifica su
% orientación.

% Para que las diferencias entre milimetros y radianes sean más o menos
% semejantes pasamos a grados.

difTh1 = difTh1*180/pi;
difTh2 = difTh2*180/pi;

dif = [difTh1, difTh2, difD3];

if app.selecc == 0
    warndlg('Seleccione la velocidad de simulación', 'Aviso','modal')
else
rep = round(max(dif)/(2*app.vel),0); % tiene que ser un número entero

% Para poder representar el robot en las sucesivas posiciones hasta llegar a
% la posición final deseada, primero habrá que determinar los sucesivos 
% valores de las variables y después calcular la posición correspondiente 
% al end deffector para cada conjunto de valores. 

% PASO 1: Determinación de los sucesivos valores de las VAR
% Vamos a definir un vector fila por cada variable usando la función 
% linspace(x1,x2,N). Esta función nos proporciona un vector fila con N 
% puntos igualmente espaciados que van desde X1 a X2, en nuestro caso 
% desde el valor inicial de la variable,según pos_initial, hasta el valor 
% final que hayamos introducido.

Th1_path = linspace(pos_initial(1),th1,rep);
Th2_path = linspace(pos_initial(2),th2,rep);
d3_path  = linspace(pos_initial(3),d3,rep);
Th4_path = linspace(pos_initial(4),th4,rep);

% PASO 2: Cálculo posiciones sucesivas del end-deffector
% Vamos a definir una matriz P_path nx3 ( 3 columnas: coord x, y, z / n filas
% para los 20 puntos asociados a los n conjuntos de variables) que
% contenga todos los puntos de la trayectoria seguida por el end deffector
% hasta llegar a su posición final. Para obtener las coord de estos puntos
% habra que resolver el problema directo para cada conjunto de valores de
% nuestras variables, para ello, utilizaremos un ciclo for.

% Guardamos espacio para P_path inicializando la matriz con todo ceros:

P_path = zeros(rep,3);

for i = 1:1:rep % ciclo for que va desde 1 hasta 20 con incrementos de 1
    P_path(i,1) = app.a1*cos(Th1_path(i)) + app.a2*cos(Th1_path(i)+Th2_path(i));%x
    P_path(i,2) = app.a1*sin(Th1_path(i)) + app.a2*sin(Th1_path(i)+Th2_path(i));%y
    P_path(i,3) = app.d1 - d3_path(i); %z
end

% Una vez definidos todos los puntos que adopata el end-deffector hasta 
% llegara  ala posición final, es decir, una vez definida la trayectoria
% seguida por el end-deffector, vamos a representarla. Al tratarse de una
% trayectoria en 3D ( ejes x,y,z) utilizaremos la función plot3.Pero antes
% de nada eliminaremos la trayectoria del end-deffector de simulaciones 
% previas si es que existe.

% en este caso para almacenar la trayectoria del End-Deffector y poder
% usarla en simulaciones posteriores la defino como una propiedad. Por lo
% tanto, en vez de comprobar si existe, porque la propiedad está creada
% desde el principio, hay que comprobar si esta vacia o no. 

if app.n > 0                      % en el caso de que esta no sea la primera 
   delete(app.trayecEndDef)       % simulación se eliminará la trayectoria
end                               % anterior  

% En el caso de que se haya hecho una simulación del problema inverso 
% justo antes de una simulación del problema directo  
% existirá un punto previo que habrá que eliminar para hacer la nueva
% representación

if app.m > 0                    
    delete(app.point)
end

if app.l > 0
    delete (app.points)
    delete(app.trayecTotEndDef)
end

app.trayecEndDef = plot3(P_path(:,1),P_path(:,2),P_path(:,3),'Color',[1 0 0], 'LineWidth',2);
% P_path(:,1) es el vector que contiene todas las coordenadas x de los
% puntos, que están almacenadas en la primera columna de la matriz P_path.

hold on % mantiene el gráfico actual para que los comandos gráficos 
% subsiguientes se agreguen a los existentes sin restablecer el color y el 
% estilo de línea.

% definir limites de plot 
xlim([-400,1000]);
ylim([-700,700]);
zlim([0,1000]);

% Procedemos a representar el robot en las sucesivas posiciones hasta
% llegar a la posición. Para ello, al igual que hemos hecho con la 
% trayectoria seguida por el end-deffector emplearemos un bucle for.

for i=1:rep  
    scara.plot([Th1_path(i) Th2_path(i) d3_path(i) Th4_path(i)], ...
        'scale',0.5,'perspective','jointdiam',2,'jaxes','shadow');
end

% Guardo la posición final del robot, el valor final de mis variables, para
% que en la siguiente simulación esa sea la posición inicial del robot.

app.pos_in = [th1 th2 d3 th4];
app.n = app.n +1;
end
end
        end

        % Button pushed function: InvBut
        function InvButButtonPushed(app, event)
% Función para resolver el problema INVERSO en un robot Scara usando el 
% Robotic Toolbox de Peter Corke que permite crear un robot a partir de 
% los parámetros de DH.


% En el problema inverso el objetivo es calcular el valor que tienen que
% adoptar las variables para que el en-deffector llegue a una posción dada. 
% Dicha posición ha sido definida por el usuario:
            
            x = app.X.Value;
            y = app.Y.Value;
            z = app.Z.Value;
            theta4 = app.Orientacion.Value*pi/180; %tiene que estar en rad

% Resolvemos el problema inverso según la posición asiganda al 
% end-deffector,es decir, calculamos el valor de las variables 
% (theta1, theta2, d3 y theta4)

% Antes de nada vamos a comprobar si las posiciones definidas son posibles
% de alcanzar con el robot. 

D = (x^2+y^2-app.a1^2-app.a2^2)/(2*app.a1*app.a2);

if D^2 > 1
    warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
else 
    % Cálculo theta2 en rad:
    if y >= 0
    th2 = atan2(sqrt(1-D^2),D); % conf ABIERTA
    else 
    th2 = atan2(-sqrt(1-D^2),D); % conf CERRADA  
    end
        th_2 = th2*180/pi; %paso a grados
        if  th_2 <-150 || th_2 > 150
        warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
        else 
        % Cálculo theta1 en rad:
        th1 = atan2(y,x)-atan2(app.a2*sin(th2),app.a1+app.a2*cos(th2));
        th_1 = th1*180/pi;
            if th_1 <-105 ||  th_1 > 105
            warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
            else
            % Cálculo d3:
            d3 = app.d1-z;
                if d3 > 210 || d3 < 0
                warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
                else 
            %disp('Punto dentro del espacio de trabajo del robot Scara')
app.ProbDir = 1; % tras todas las comprobaciones el punto está dentro del 
% espacio de trabajo del robot por lo que se podrán calcular las matrices
% de transformación asociadas.

% Guardo los valores introducidos por el usuario en un vector para cuando
% se quierq repetir la simulación poder verificar que no se han
% modificado los datos.

app.PosInvRep = [ x, y, z, app.Orientacion.Value];  
app.VarInvRep = [th1, th2,d3,app.Orientacion.Value*pi/180];


% En el caso de que el punto esté dentro del espacio de trabajo del robot 
% procedemos a resolver el problema inverso y a representar la trayectoria 
% del robot. 

% Primero devolvemos a las casillas Theta1, Theta2, d3 y Theta4 según el
% punto asignado su valor correspondiente:

        app.Theta1.Value = round(th_1,3); % redondeo a 3 cifras decimales
        app.Theta2.Value = round(th_2,3);
        app.d3.Value = round(d3,3);
        app.Theta4.Value = app.Orientacion.Value;


% Creamos las articulaciones  mediante la función Link. Esta
% función crea las articulaciones usando los paramétros de DH. Sólo tiene 3
% entradas ya que el parámetro que no se incluye lo asume como variable, y
% en función de si este parametro variable es theta o d, la articulación
% será revolute o prismatic. En el caso del par prismático hay que
% espicificar sus valores límite. 

L1 = Link('d',app.d1,'a',app.a1,'alpha',0,'qlim',[-105*pi/180,105*pi/180]); %R
L2 = Link('d',0,'a',app.a2,'alpha',pi,'qlim',[-157,5*pi/180,157,5*pi/180]);  %R
L3 = Link('theta',0,'a',0,'alpha',0,'qlim',[0,210]); %P 
L4 = Link('d',0,'a',0,'alpha',0); %R no tiene limitación de giro


% Armamos el robot con la función SerialLink que crea la cadena cinemática.

scara = SerialLink([L1,L2,L3,L4],'name','Scara');


% Definimos una posición de partida del robot pos_offset(th1,th2,d3,th4)
% que se corresponde con la posición de reposo:

pos_offset = [0 0 0 0];

% En el caso de que el robot parta de otra posición ya definida (este sería
% el caso de varias simulaciones) su posición inicial será esa, en caso
% contrario la posición inicial se corresponderá con la pos_offset.

if app.n > 0 || app.m > 0 || app.l
  pos_initial = app.pos_in;
else 
    pos_initial = pos_offset; % en la primera simulación se parte de la 
                             % pos_offset
end

app.PosRep = pos_initial;


% DETERMINACIÓN DEL PASO PARA LA SIMULACIÓN
% En función de la diferencia entre los valores introducidos (distancia) se
% representarán más o menos puntos de precisión. De esta manera, se
% conseguirá que todas las simulaciones tengan una precisión similar.

difTh1 = abs(pos_initial(1)-th1)*180/pi; % en grados
difTh2 = abs(pos_initial(2)-th2)*180/pi;
difD3 = abs(pos_initial(3)-d3); % en milimetros

dif = [difTh1, difTh2, difD3];

if app.selecc == 0
    warndlg('Seleccione la velocidad de simulación', 'Aviso','modal')
else

rep = round(max(dif)/(2*app.vel),0); % tiene que ser un número entero


% Para asegurase de que todos los puntos de la trayectoria seguida están
% denttro del espacio de trabajo, la trayectoria se definirá variando el
% valor de las variables articulares del robot (th1, th2, d3 y th4) desde
% la posición inicial hasta la posición final. Los valores de la posición 
% final son los calculados resolviendo el problema inverso correspondientes
% a la posición introducida por el usuario.

% Por lo tanto, se procederá igual que en el caso del problema directo,
% primero habrá que determinar los sucesivos 
% valores de las variables y después calcular la posición correspondiente 
% al end deffector para cada conjunto de valores. 

% PASO 1: Determinación de los sucesivos valores de las VAR
% Vamos a definir un vector fila por cada variable usando la función 
% linspace(x1,x2,N). Esta función nos proporciona un vector fila con N 
% puntos igualmente espaciados que van desde X1 a X2, en nuestro caso 
% desde el valor inicial de la variable,según pos_initial, hasta el valor 
% final que hayamos introducido.

Th1_path = linspace(pos_initial(1),th1,rep);
Th2_path = linspace(pos_initial(2),th2,rep);
d3_path  = linspace(pos_initial(3),d3,rep);
Th4_path = linspace(pos_initial(4),app.Orientacion.Value*pi/180,rep);

% PASO 2: Cálculo posiciones sucesivas del end-deffector
% Vamos a definir una matriz P_path rep x 3 (3 columnas: coord x, y, z/ 
% rep filas para los puntos asociados a los rep conjuntos de 
% variables) que contenga todos los puntos de la trayectoria seguida por 
% el end deffector hasta llegar a su posición final. Para obtener las coord 
% de estos puntos  habra que resolver el problema directo para cada conjunto 
% de valores de nuestras variables, para ello, utilizaremos un ciclo for.

% Guardamos espacio para P_path inicializando la matriz con todo ceros:

P_path = zeros(rep,3);

for i = 1:1:rep % ciclo for que va desde 1 hasta 20 con incrementos de 1
    P_path(i,1) = app.a1*cos(Th1_path(i)) + app.a2*cos(Th1_path(i)+Th2_path(i));%x
    P_path(i,2) = app.a1*sin(Th1_path(i)) + app.a2*sin(Th1_path(i)+Th2_path(i));%y
    P_path(i,3) = app.d1 - d3_path(i); %z
end


% Una vez calculados tanto los puntos de la trayectoria del 
% end-deffector como las variables asociadas a cada punto procedemos a
% representar el robot.

% Primero, represento la trayectoria seguida por el end-deffector. 
% Al tratarse de una trayectoria en 3D ( ejes x,y,z) utilizaremos la 
% función plot3.Pero, antes de nada eliminaremos la trayectoria del
% end-deffector de simulaciones previas si es que existe y el punto de la
% previa simulación.

if app.n > 0                 % en el caso de que esta no sea la primera 
   delete(app.trayecEndDef)  % simulación (da igual si del problema inverso  
                             %  o del directo) se eliminará la trayectoria
end 
% en el caso de que no sea la primera simulación del problema inverso 
% existirá un punto previo que habrá que eliminar para hacer la nueva
% representación
if app.m > 0                    
    delete(app.point)
     delete(app.trayecEndDef)
end

if app.l > 0
    delete (app.points)
    delete(app.trayecTotEndDef)
end


app.trayecEndDef = plot3(P_path(:,1),P_path(:,2),P_path(:,3),'Color',[1 0 0], 'LineWidth',2);

hold on

% definimos los límites del plot

xlim([-400,1000]);
ylim([-700,700]);
zlim([0,1000]);

% A continuación, represento el punto al que el usuario quiere mover el
% end-deffector usando una esfera amarilla y represento el punto de origen 
% con una esfera verde:

% Primero cálculamos la posisción inical (x0,y0,z0):

x0 = app.a1*cos(pos_initial(1)) + app.a2*cos(pos_initial(1)+pos_initial(2));
y0 = app.a1*sin(pos_initial(1)) + app.a2*sin(pos_initial(1)+pos_initial(2));
z0 = app.d1 - pos_initial(3);
 
app.point = [plot_sphere([x,y,z], 30, 'y'), plot_sphere([x0,y0,z0],30,'g')];

% Finalemnete, procedemos a representar el robot en las sucesivas 
% posiciones hasta llegar a la posición asignada (esfera amarilla). 
% Para ello, emplearemos un bucle for.

for i=1:rep
    
    scara.plot([Th1_path(i) Th2_path(i) d3_path(i) Th4_path(i)], ...
        'scale',0.5,'perspective','jointdiam',2,'jaxes','shadow');
end

% Guardo la posición final del robot, el valor final de mis variables, para
% que en la siguiente simulación esa sea la posición inicial del robot.

app.pos_in= [th1 th2 d3 Th4_path(rep)]; 
app.m = app.m+1;
%app.s = rep;
end
                end
            end
        end
end


        end

        % Value changed function: SeleccinmatrizDropDown
        function SeleccinmatrizDropDownValueChanged(app, event)
            value = app.SeleccinmatrizDropDown.Value;
         % Función que calcula las matrices de transformación
         %    - Si value = 1 -> Cálculo T01
         %      T01 = matriz de transformación que relaciona el sistema de referencia
         %      fijo con el elemento 1
         %    - Si value = 2 -> Cálculo T12
         %      T12 = matriz de transformación que relaciona elemento 1 con el 2
         %    - Si value = 3 -> Cálculo T23
         %      T23 = matriz de transformación que relaciona elemento 2 con el 3
         %    - Si value = 5 -> Cálculo T34
         %      T34 = matriz de transformación que relaciona elemento 3 con el 4
         %    - Si value = 6 -> Cálculo T04
         %      T04 = matriz de transformación que relaciona el sistema de referencia
         % 
         % Antes de nada, vamos a expresar los parámetros de DH en una
         % matriz:
         
            alpha1 = 0;
            theta1 = app.Theta1.Value*pi/180;
          
            d2     = 0;
            alpha2 = pi;
            theta2 = app.Theta2.Value*pi/180;
           
            a3     = 0;
            d3     = app.d3.Value;
            alpha3 = 0;
            theta3 = 0;

            a4     = 0;
            d4     = 0;
            alpha4 = 0;
            theta4 = app.Theta4.Value*pi/180;
        
         DHparam = [ app.a1     alpha1   app.d1  theta1 ;
                     app.a2     alpha2   d2      theta2 ;
                     a3         alpha3   d3      theta3 ; 
                     a4         alpha4   d4      theta4 ];

         % Las matrices de transformación intermedias tienen todas la 
         % misma forma así que se escribira la forma genereal con variables 
         % simbólicas y después se sustituirán los parámetros de DH en 
         % cada caso:

            syms alph theta d a


T= [cos(theta) -sin(theta)*cos(alph) sin(theta)*sin(alph) a*cos(theta);
    sin(theta) cos(theta)*cos(alph) -cos(theta)*sin(alph) a*sin(theta);
        0              sin(alph)            cos(alph)           d;
        0                   0                   0               1       ];


         % Para ver que opción ha elegido el usuario y según la elección
         % calcular la matriz de transformación correspondiente se 
         % creará empleará el comando if.
     
if app.ProbDir == 1 % sólo si los valores introducidos son válidos se calcularán las matrices
        if value == '1' % Cálculo T01
             T01 = zeros(4,4); % Primero dimensiono la matriz
             T01 = subs(T, [a alph d theta], [app.a1 alpha1 app.d1 theta1]);

             app.ThetaDH.Value = app.Theta1.Value;
             app.AlphaDH.Value = alpha1;
             app.aDH.Value = app.a1;
             app.dDH.Value = app.d1;

             app.a11.Value = round(double(T01(1,1)),3);
             app.a12.Value = round(double(T01(1,2)),3);
             app.a13.Value = round(double(T01(1,3)),3);
             app.a14.Value = round(double(T01(1,4)),3);

             app.a21.Value = round(double(T01(2,1)),3);
             app.a22.Value = round(double(T01(2,2)),3);
             app.a23.Value = round(double(T01(2,3)),3);
             app.a24.Value = round(double(T01(2,4)),3);

             app.a31.Value = round(double(T01(3,1)),3);
             app.a32.Value = round(double(T01(3,2)),3);
             app.a33.Value = round(double(T01(3,3)),3);
             app.a34.Value = round(double(T01(3,4)),3);

             app.a41.Value = round(double(T01(4,1)),3);
             app.a42.Value = round(double(T01(4,2)),3);
             app.a43.Value = round(double(T01(4,3)),3);
             app.a44.Value = round(double(T01(4,4)),3);
         

         elseif value == '2' % Cálculo T12
             T12 = zeros(4,4); % Primero dimensiono la matriz
             T12 = subs(T, [a alph d theta], [app.a2 alpha2 d2 theta2]);

             app.ThetaDH.Value = app.Theta2.Value;
             app.AlphaDH.Value = alpha2 * 180/pi; % en grados
             app.aDH.Value = app.a2;
             app.dDH.Value = d2;

             app.a11.Value = round(double(T12(1,1)),3);
             app.a12.Value = round(double(T12(1,2)),3);
             app.a13.Value = round(double(T12(1,3)),3);
             app.a14.Value = round(double(T12(1,4)),3);

             app.a21.Value = round(double(T12(2,1)),3);
             app.a22.Value = round(double(T12(2,2)),3);
             app.a23.Value = round(double(T12(2,3)),3);
             app.a24.Value = round(double(T12(2,4)),3);

             app.a31.Value = round(double(T12(3,1)),3);
             app.a32.Value = round(double(T12(3,2)),3);
             app.a33.Value = round(double(T12(3,3)),3);
             app.a34.Value = round(double(T12(3,4)),3);

             app.a41.Value = round(double(T12(4,1)),3);
             app.a42.Value = round(double(T12(4,2)),3);
             app.a43.Value = round(double(T12(4,3)),3);
             app.a44.Value = round(double(T12(4,4)),3);
         

         elseif value == '3' % Cálculo T23
             T23 = zeros(4,4); % Primero dimensiono la matriz
             T23 = subs(T, [a alph d theta], [a3 alpha3 d3 theta3]);

             app.ThetaDH.Value = theta3;
             app.AlphaDH.Value = alpha3; % en grados
             app.aDH.Value = a3;
             app.dDH.Value = d3;


             app.a11.Value = round(double(T23(1,1)),3);
             app.a12.Value = round(double(T23(1,2)),3);
             app.a13.Value = round(double(T23(1,3)),3);
             app.a14.Value = round(double(T23(1,4)),3);

             app.a21.Value = round(double(T23(2,1)),3);
             app.a22.Value = round(double(T23(2,2)),3);
             app.a23.Value = round(double(T23(2,3)),3);
             app.a24.Value = round(double(T23(2,4)),3);

             app.a31.Value = round(double(T23(3,1)),3);
             app.a32.Value = round(double(T23(3,2)),3);
             app.a33.Value = round(double(T23(3,3)),3);
             app.a34.Value = round(double(T23(3,4)),3);

             app.a41.Value = round(double(T23(4,1)),3);
             app.a42.Value = round(double(T23(4,2)),3);
             app.a43.Value = round(double(T23(4,3)),3);
             app.a44.Value = round(double(T23(4,4)),3);
         

         elseif value == '4' % Cálculo T34
             T34 = zeros(4,4); % Primero dimensiono la matriz
             T34 = subs(T, [a alph d theta], [a4 alpha4 d4 theta4]);

             app.ThetaDH.Value = app.Theta4.Value;
             app.AlphaDH.Value = alpha4; % en grados
             app.aDH.Value = a4;
             app.dDH.Value = d4;

             app.a11.Value = round(double(T34(1,1)),3);
             app.a12.Value = round(double(T34(1,2)),3);
             app.a13.Value = round(double(T34(1,3)),3);
             app.a14.Value = round(double(T34(1,4)),3);

             app.a21.Value = round(double(T34(2,1)),3);
             app.a22.Value = round(double(T34(2,2)),3);
             app.a23.Value = round(double(T34(2,3)),3);
             app.a24.Value = round(double(T34(2,4)),3);

             app.a31.Value = round(double(T34(3,1)),3);
             app.a32.Value = round(double(T34(3,2)),3);
             app.a33.Value = round(double(T34(3,3)),3);
             app.a34.Value = round(double(T34(3,4)),3);

             app.a41.Value = round(double(T34(4,1)),3);
             app.a42.Value = round(double(T34(4,2)),3);
             app.a43.Value = round(double(T34(4,3)),3);
             app.a44.Value = round(double(T34(4,4)),3);
         

         elseif value == '5' % Cálculo T04
             msgbox('No tiene párametros de DH asociados','Información','modal');

             % Pongo 0 a todos aunque no influye en nada
             app.ThetaDH.Value = 0;
             app.AlphaDH.Value = 0; % en grados
             app.aDH.Value = 0;
             app.dDH.Value = 0;
             
             T04 = zeros(4,4);
             T01 = zeros(4,4);
             T12 = zeros(4,4);
             T23 = zeros(4,4);
             T34 = zeros(4,4);

             T01 = subs(T, [a alph d theta], [app.a1 alpha1 app.d1 theta1]);
             T12 = subs(T, [a alph d theta], [app.a2 alpha2 d2 theta2]);
             T23 = subs(T, [a alph d theta], [a3 alpha3 d3 theta3]);
             T34 = subs(T,[a alph d theta], [a4 alpha4 d4 theta4]);
             T04 = T01 * T12 * T23 * T34;

             app.a11.Value = round(double(T04(1,1)),3);
             app.a12.Value = round(double(T04(1,2)),3);
             app.a13.Value = round(double(T04(1,3)),3);
             app.a14.Value = round(double(T04(1,4)),3);

             app.a21.Value = round(double(T04(2,1)),3);
             app.a22.Value = round(double(T04(2,2)),3);
             app.a23.Value = round(double(T04(2,3)),3);
             app.a24.Value = round(double(T04(2,4)),3);

             app.a31.Value = round(double(T04(3,1)),3);
             app.a32.Value = round(double(T04(3,2)),3);
             app.a33.Value = round(double(T04(3,3)),3);
             app.a34.Value = round(double(T04(3,4)),3);

             app.a41.Value = round(double(T04(4,1)),3);
             app.a42.Value = round(double(T04(4,2)),3);
             app.a43.Value = round(double(T04(4,3)),3);
             app.a44.Value = round(double(T04(4,4)),3);
        end
end

      
        end

        % Button pushed function: pto1
        function pto1ButtonPushed(app, event)

% Coordenadas punto1:
            x = app.x1.Value;
            y = app.y1.Value;
            z = app.z1.Value;
            
% Resolvemos el problema inverso según la posición asiganda al 
% end-deffector,es decir, calculamos el valor de las variables 
% (theta1, theta2, d3 y theta4)

% Antes de nada vamos a comprobar si las posiciones definidas son posibles
% de alcanzar con el robot. 

D = (x^2+y^2-app.a1^2-app.a2^2)/(2*app.a1*app.a2);

if D^2 > 1
    warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
else 
    if y >= 0
    th2 = atan2(sqrt(1-D^2),D); % conf ABIERTA
    else 
    th2 = atan2(-sqrt(1-D^2),D); % conf CERRADA  
    end
 th_2 = th2*180/pi; %paso a grados

    if  th_2 <-150 || th_2 > 150
    warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
    else 
    % Cálculo theta1 en rad:
    th1 = atan2(y,x)-atan2(app.a2*sin(th2),app.a1+app.a2*cos(th2));
    th_1 = th1*180/pi;
  
        if th_1 <-105 ||  th_1 > 105
        warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
        else
        % Cálculo d3:
         d3 = app.d1-z;
            if d3 > 210 || d3 < 0
            warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
            else 
            % Punto introducido es válido:
            msgbox('Punto dentro del espacio de trabajo del robot Scara','Aviso','modal')
            app.p1 = 1; %punto 1 es válido
            end
        end
    end
    app.Th1Trayect(2)= th1;
    app.Th2Trayect(2) = th2;
    app.d3Trayect(2) = d3;
    app.Th4Trayect(2) = app.Orientacion1.Value*pi/180;

end   

        end

        % Button pushed function: pto2
        function pto2ButtonPushed(app, event)
% Repetimos la comprobación hecha para el punto 1:

% Coordenadas punto1:
            x = app.x2.Value;
            y = app.y2.Value;
            z = app.z2.Value;

% Resolvemos el problema inverso según la posición asiganda al 
% end-deffector,es decir, calculamos el valor de las variables 
% (theta1, theta2, d3 y theta4)

% Antes de nada vamos a comprobar si las posiciones definidas son posibles
% de alcanzar con el robot. 

D = (x^2+y^2-app.a1^2-app.a2^2)/(2*app.a1*app.a2);

if D^2 > 1
    warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
else
    if y >= 0
    th2 = atan2(sqrt(1-D^2),D); % conf ABIERTA
    else 
    th2 = atan2(-sqrt(1-D^2),D); % conf CERRADA  
    end
th_2 = th2*180/pi; %paso a grados

    if  th_2 <-150 || th_2 > 150
    warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
    else 
    % Cálculo theta1 en rad:
    th1 = atan2(y,x)-atan2(app.a2*sin(th2),app.a1+app.a2*cos(th2));
    th_1 = th1*180/pi;
  
        if th_1 <-105 ||  th_1 > 105
        warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
        else
        % Cálculo d3:
         d3 = app.d1-z;
            if d3 > 210 || d3 < 0
            warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
            else 
            % Punto introducido es válido:
            msgbox('Punto dentro del espacio de trabajo del robot Scara','Aviso','modal')
            app.p2 = 1;
            end
        end
    end
    app.Th1Trayect(3)= th1;
    app.Th2Trayect(3) = th2;
    app.d3Trayect(3) = d3;
    app.Th4Trayect(3) = app.Orientacion2.Value*pi/180;
end   
        end

        % Button pushed function: pto3
        function pto3ButtonPushed(app, event)
% Repetimos la comprobación hecha para los puntos anteriores:


% Coordenadas punto1:
            x = app.x3.Value;
            y = app.y3.Value;
            z = app.z3.Value;

% Resolvemos el problema inverso según la posición asiganda al 
% end-deffector,es decir, calculamos el valor de las variables 
% (theta1, theta2, d3 y theta4)

% Antes de nada vamos a comprobar si las posiciones definidas son posibles
% de alcanzar con el robot. 

D = (x^2+y^2-app.a1^2-app.a2^2)/(2*app.a1*app.a2);

if D^2 > 1
    warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
else 
    if y >= 0
    th2 = atan2(sqrt(1-D^2),D); % conf ABIERTA
    else 
    th2 = atan2(-sqrt(1-D^2),D); % conf CERRADA  
    end
th_2 = th2*180/pi; %paso a grados

    if  th_2 <-150 || th_2 > 150
    warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
    else 
    % Cálculo theta1 en rad:
    th1 = atan2(y,x)-atan2(app.a2*sin(th2),app.a1+app.a2*cos(th2));
    th_1 = th1*180/pi;
  
        if th_1 <-105 ||  th_1 > 105
        warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
        else
        % Cálculo d3:
         d3 = app.d1-z;
            if d3 > 210 || d3 < 0
            warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
            else 
            % Punto introducido es válido:
            msgbox('Punto dentro del espacio de trabajo del robot Scara','Aviso','modal')
            app.p3 = 1;
            end
        end
    end
    app.Th1Trayect(4)= th1;
    app.Th2Trayect(4) = th2;
    app.d3Trayect(4) = d3;
    app.Th4Trayect(4) = app.Orientacion3.Value*pi/180;
end   
        end

        % Button pushed function: pto4
        function pto4ButtonPushed(app, event)
% Repetimos la comprobación hecha para los puntos anteriores:

% Coordenadas punto1:
            x = app.x4.Value;
            y = app.y4.Value;
            z = app.z4.Value;

% Resolvemos el problema inverso según la posición asiganda al 
% end-deffector,es decir, calculamos el valor de las variables 
% (theta1, theta2, d3 y theta4)

% Antes de nada vamos a comprobar si las posiciones definidas son posibles
% de alcanzar con el robot. 

D = (x^2+y^2-app.a1^2-app.a2^2)/(2*app.a1*app.a2);

if D^2 > 1
    warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
else 
    if y >= 0
    th2 = atan2(sqrt(1-D^2),D); % conf ABIERTA
    else 
    th2 = atan2(-sqrt(1-D^2),D); % conf CERRADA  
    end
th_2 = th2*180/pi; %paso a grados

    if  th_2 <-150 || th_2 > 150
    warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
    else 
    % Cálculo theta1 en rad:
    th1 = atan2(y,x)-atan2(app.a2*sin(th2),app.a1+app.a2*cos(th2));
    th_1 = th1*180/pi;
    
        if th_1 <-105 ||  th_1 > 105
        warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
        else
        % Cálculo d3:
         d3 = app.d1-z;
            if d3 > 210 || d3 < 0
            warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
            else 
            % Punto introducido es válido:
            msgbox('Punto dentro del espacio de trabajo del robot Scara','Aviso','modal')
            app.p4 = 1;
            end
        end
    end
    app.Th1Trayect(5)= th1;
    app.Th2Trayect(5) = th2;
    app.d3Trayect(5) = d3;
    app.Th4Trayect(5) = app.Orientacion4.Value*pi/180;
end   
        end

        % Button pushed function: pto5
        function pto5ButtonPushed(app, event)
% Repetimos la comprobación hecha para los puntos anteriores:
% Coordenadas punto1:
            x = app.x5.Value;
            y = app.y5.Value;
            z = app.z5.Value;

% Resolvemos el problema inverso según la posición asiganda al 
% end-deffector,es decir, calculamos el valor de las variables 
% (theta1, theta2, d3 y theta4)

% Antes de nada vamos a comprobar si las posiciones definidas son posibles
% de alcanzar con el robot. 

D = (x^2+y^2-app.a1^2-app.a2^2)/(2*app.a1*app.a2);
if D^2 > 1
    warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
else 
% Cálculo theta2 en rad:
    if y >= 0
    th2 = atan2(sqrt(1-D^2),D); % conf ABIERTA
    else 
    th2 = atan2(-sqrt(1-D^2),D); % conf CERRADA  
    end
th_2 = th2*180/pi; %paso a grados
    if  th_2 <-150 || th_2 > 150
    warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
    else 
    % Cálculo theta1 en rad:
    th1 = atan2(y,x)-atan2(app.a2*sin(th2),app.a1+app.a2*cos(th2));
    th_1 = th1*180/pi;
        if th_1 <-105 ||  th_1 > 105
        warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
        else
        % Cálculo d3:
         d3 = app.d1-z;
            if d3 > 210 || d3 < 0
            warndlg('Punto fuera del espacio de trabajo del robot Scara','Aviso','modal')
            else 
            % Punto introducido es válido:
            msgbox('Punto dentro del espacio de trabajo del robot Scara','Aviso','modal')
            app.p5 = 1;
            end
        end
    end
    app.Th1Trayect(6)= th1;
    app.Th2Trayect(6) = th2;
    app.d3Trayect(6) = d3;
    app.Th4Trayect(6) = app.Orientacion5.Value*pi/180;
end  


        end

        % Button pushed function: trayect
        function trayectButtonPushed(app, event)
% Antes de nada, se va a comprobar que todos los puntos que se han
% introducido son válidos y que no se ha ignorado el mensaje de aviso.

% En el caso de que los puntos sean válidos, las coordenadas se 
% almacenarán en 3 vectores (uno para X, otro para Y y otro para Z). 
% Además, para que la trayectoria solo se represente si los puntos son
% válidos se definirá una variable (propiedad) c que valdrá 1 en el caso de
% que todos los puntos sean correctos.

% El tamaño de estos vectores dependerá del número de puntos que se 
% quieran introducir.

if app.np == 2
    
    if (app.p1 == 1) && (app.p2 ==1)
        
       % Inicializamos los vectores
       xx = linspace(0,0,3);
       yy = linspace(0,0,3);
       zz = linspace(0,0,3);
       orientacion = linspace(0,0,3);

       % Almacenamos las coordenadas
       % Punto 1:
       xx(2) = app.x1.Value;
       yy(2) = app.y1.Value;
       zz(2) = app.z1.Value;
       orientacion(2) = app.Orientacion1.Value*pi/180; %en rad
       % Punto 2:
       xx(3) = app.x2.Value;
       yy(3) = app.y2.Value;
       zz(3) = app.z2.Value;
       orientacion(3) = app.Orientacion2.Value*pi/180;

       % Para poder almacenar los puntos y poder repetir la simulación creo
       % una matriz

       app.PointsRep = [0     0     0     0; 
                        xx(2) yy(2) zz(2) app.Orientacion1.Value;
                        xx(3) yy(3) zz(3) app.Orientacion2.Value];

       % Para que se represente la trayectoria:
       app.c = 1;
    else
        warndlg('Uno de los dos puntos no es válido')

    end

elseif app.np == 3
    if app.p1 == 1 && app.p2 ==1 && app.p3 == 1
       % Inicializamos los vectores
       xx = linspace(0,0,4);
       yy = linspace(0,0,4);
       zz = linspace(0,0,4);
       orientacion = linspace(0,0,4);

       % Almacenamos las coordenadas
       % Punto 1:
       xx(2) = app.x1.Value;
       yy(2) = app.y1.Value;
       zz(2) = app.z1.Value;
       orientacion(2) = app.Orientacion1.Value*pi/180;
       % Punto 2:
       xx(3) = app.x2.Value;
       yy(3) = app.y2.Value;
       zz(3) = app.z2.Value;
       orientacion(3) = app.Orientacion2.Value*pi/180;
       % Punto 3:
       xx(4) = app.x3.Value;
       yy(4) = app.y3.Value;
       zz(4) = app.z3.Value;
       orientacion(4) = app.Orientacion3.Value*pi/180;

       % Para poder almacenar los puntos y poder repetir la simulación creo
       % una matriz

       app.PointsRep = [0     0     0     0; 
                        xx(2) yy(2) zz(2) app.Orientacion1.Value;
                        xx(3) yy(3) zz(3) app.Orientacion2.Value;
                        xx(4) yy(4) zz(4) app.Orientacion3.Value];

       % Para que se represente la trayectoria:
       app.c = 1;
    else
        warndlg('Uno de los dos puntos no es válido')

    end

elseif app.np == 4
    if app.p1 == 1 && app.p2 ==1 && app.p3 == 1 && app.p4 == 1
       % Inicializamos los vectores
       xx = linspace(0,0,5);
       yy = linspace(0,0,5);
       zz = linspace(0,0,5);
       orientacion = linspace(0,0,5);

       % Almacenamos las coordenadas
       % Punto 1:
       xx(2) = app.x1.Value;
       yy(2) = app.y1.Value;
       zz(2) = app.z1.Value;
       orientacion(2) = app.Orientacion1.Value*pi/180;
       % Punto 2:
       xx(3) = app.x2.Value;
       yy(3) = app.y2.Value;
       zz(3) = app.z2.Value;
       orientacion(3) = app.Orientacion2.Value*pi/180;
       % Punto 3:
       xx(4) = app.x3.Value;
       yy(4) = app.y3.Value;
       zz(4) = app.z3.Value;
       orientacion(4) = app.Orientacion3.Value*pi/180;
       % Punto 4:
       xx(5) = app.x4.Value;
       yy(5) = app.y4.Value;
       zz(5) = app.z4.Value;
       orientacion(5) = app.Orientacion4.Value*pi/180;

       % Para poder almacenar los puntos y poder repetir la simulación creo
       % una matriz

       app.PointsRep = [0     0     0     0; 
                        xx(2) yy(2) zz(2) app.Orientacion1.Value;
                        xx(3) yy(3) zz(3) app.Orientacion2.Value;
                        xx(4) yy(4) zz(4) app.Orientacion3.Value;
                        xx(5) yy(5) zz(5) app.Orientacion4.Value];

       % Para que se represente la trayectoria:
       app.c = 1;
    else
        warndlg('Uno de los dos puntos no es válido')

    end

elseif app.np == 5
    if app.p1 == 1 && app.p2 ==1 && app.p3 == 1 && app.p4 == 1 && app.p5 == 1
       % Inicializamos los vectores
       xx = linspace(0,0,6);
       yy = linspace(0,0,6);
       zz = linspace(0,0,6);
       orientacion = linspace(0,0,6);

       % Almacenamos las coordenadas
       % Punto 1:
       xx(2) = app.x1.Value;
       yy(2) = app.y1.Value;
       zz(2) = app.z1.Value;
       orientacion(2) = app.Orientacion1.Value;
       % Punto 2:
       xx(3) = app.x2.Value;
       yy(3) = app.y2.Value;
       zz(3) = app.z2.Value;
       orientacion(3) = app.Orientacion2.Value*pi/180;
       % Punto 3:
       xx(4) = app.x3.Value;
       yy(4) = app.y3.Value;
       zz(4) = app.z3.Value;
       orientacion(4) = app.Orientacion3.Value*pi/180;
       % Punto 4:
       xx(5) = app.x4.Value;
       yy(5) = app.y4.Value;
       zz(5) = app.z4.Value;
       orientacion(5) = app.Orientacion4.Value*pi/180;
       % Punto 5:
       xx(6) = app.x5.Value;
       yy(6) = app.y5.Value;
       zz(6) = app.z5.Value;
       orientacion(6) = app.Orientacion5.Value*pi/180;

       % Para poder almacenar los puntos y poder repetir la simulación creo
       % una matriz

       app.PointsRep = [0     0     0     0; 
                        xx(2) yy(2) zz(2) orientacion(2);
                        xx(3) yy(3) zz(3) orientacion(3);
                        xx(4) yy(4) zz(4) orientacion(4);
                        xx(5) yy(5) zz(5) orientacion(5);
                        xx(6) yy(6) zz(6) orientacion(6)];

       % Para que se represente la trayectoria:
       app.c = 1;
    else
        warndlg('Uno de los dos puntos no es válido o no se han añadido todos los puntos.')

    end
end


% Una vez almacenados los puntos en los correspondientes vectores se
% procede a representar la trayectoria seguida por el end-deffector y el
% robot en las sucesivas posiciones. Esta representación sólo se efectuará
% en el caso de que todos los puntos sean válidos.

if app.c ==1 

% Se determinaran las trayectorias de un punto a otro (trayectorias 
% intermedias) de manera independiente pero se concatenarán para
% poder ver la trayectoria total.

% Procedemos igual que se hizo en el problema inverso:


% Creamos las articulaciones  mediante la función Link. Esta
% función crea las articulaciones usando los paramétros de DH. Sólo tiene 3
% entradas ya que el parámetro que no se incluye lo asume como variable, y
% en función de si este parametro variable es theta o d, la articulación
% será revolute o prismatic. En el caso del par prismático hay que
% espicificar sus valores límite. 

L1 = Link('d',app.d1,'a',app.a1,'alpha',0,'qlim',[-105*pi/180,105*pi/180]); %R
L2 = Link('d',0,'a',app.a2,'alpha',pi,'qlim',[-157,5*pi/180,157,5*pi/180]);  %R
L3 = Link('theta',0,'a',0,'alpha',0,'qlim',[0,210]); %P 
L4 = Link('d',0,'a',0,'alpha',0); %R no tiene limitación de giro


% Armamos el robot con la función SerialLink que crea la cadena cinemática.

scara = SerialLink([L1,L2,L3,L4],'name','Scara');

% Definimos una posición de partida del robot pos_offset(th1,th2,d3,th4)
% que se corresponde con la posición de reposo:

pos_offset = [0 0 0 0];

% En el caso de que el robot parta de otra posición ya definida (este sería
% el caso de varias simulaciones) su posición inicial será esa, en caso
% contrario la posición inicial se corresponderá con la pos_offset.

if  app.n > 0 || app.m > 0 || app.l > 0  % en el caso de que no exista pos inicial definida
    pos_initial = app.pos_in;
else 
    pos_initial = pos_offset;
end

% En el caso del problema inverso, para poder representar el robot en las 
% sucesivas posiciones hasta llegar a la posición final deseada, la asiganda,
% primero habrá que determinar los valores de las variables articulares
% y después determinar trayectoria seguida por el end-deffector,
% resolviendo el problema directo para cada conjunto de variables.

% PASO 1: Cálculo de los sucesivos valores de las articulaciones

% Vamos a definir 4 matrices cada almacenara los datos correspondientes a
% una variable (theta1, theta2, d3 o theta4).

% Las dimensiones de estas matrices serán rx20. r (r = nº de puntos introducidos)
% filas 1 por cada trayectoria entre 2 puntos consecutivos y 20 columnas 
% ya que se tomarán 20 puntos de precisión para determinar cada trayectoria. 
 
% Antes de nada calculo la posición inicial del end-deffector, 
% es decir, he de de resolver el problema directo para la poisición 
% inicial del robot.

x0 = app.a1*cos(pos_initial(1)) + app.a2*cos(pos_initial(1)+pos_initial(2));
y0 = app.a1*sin(pos_initial(1)) + app.a2*sin(pos_initial(1)+pos_initial(2));
z0 = app.d1 - pos_initial(3);

% Al igual que hemos hecho con las variables iniciales del robot, guardamos
% las coordenadas de la posición inicial en los vectores que contienen las
% coordenadas de todos los puntos significativos:

xx(1) = x0;
yy(1) = y0;
zz(1) = z0;
orientacion(1) = pos_initial(4);


app.PointsRep(1,1) = xx(1);
app.PointsRep(1,2) = yy(1);
app.PointsRep(1,3) = zz(1);
app.PointsRep(1,4) = orientacion(1); % almacenamos las orientaciones en rad

% Almaceno las variables articulares correspondientes a la posición inicial

app.Th1Trayect(1) = pos_initial(1);
app.Th2Trayect(1) = pos_initial(2);
app.d3Trayect(1) = pos_initial(3);
app.Th4Trayect(1) = pos_initial(4);

% Inicializamos las 4 matrices:
app.r = app.np; % numero de puntos introducidos

Th1_path = zeros(app.r,20);
Th2_path = zeros(app.r,20);
d3_path = zeros(app.r,20);
Th4_path = zeros(app.r,20);

% Cálculo los valores de las articulaciones correspondientes a los puntos
% introducidos para ello resuelvo el problema inverso.
% Para ir introduciendo los valores en las matrices vamos a emplear un
% ciclo for

for i = 1: app.r
    Th1_path(i,:) = linspace(app.Th1Trayect(i),app.Th1Trayect(i+1),20); 
    Th2_path(i,:) = linspace(app.Th2Trayect(i),app.Th2Trayect(i+1),20);
    d3_path(i,:) = linspace(app.d3Trayect(i),app.d3Trayect(i+1),20);
    Th4_path(i,:) = linspace(app.Th4Trayect(i),app.Th4Trayect(i+1),20);
end

% PASO 2: Determinación de los puntos de la trayectoria del end-deffector

% Vamos a definir 3 matrices una por cada coordenada. Cada matriz será de 
% rx20, r filas 1 por cada trayectoria consecutiva y 20 columnas por los 
% 20 puntos de precisión  

% Antes de nada inicializamos las matrices:

X_path = zeros(app.r,20);
Y_path = zeros(app.r,20);
Z_path = zeros(app.r,20);

% Para ir cálculando las entradas de las matrices habrá que resolver el 
% problema directo para cada conjunto de valores articulares. Para ello, 
% utilizaremos un doble ciclo for. El externo asociado al número de 
% trayectorias y el interno asociado a cada punto de precisión.

for i = 1:app.r
    for j = 1:20
        X_path(i,j) = app.a1*cos(Th1_path(i,j)) + app.a2*cos(Th1_path(i,j)+Th2_path(i,j));%x

        Y_path(i,j) = app.a1*sin(Th1_path(i,j)) + app.a2*sin(Th1_path(i,j)+Th2_path(i,j));%y
            
        Z_path(i,j) = app.d1 - d3_path(i,j); %z
    end
    
end


% PASO 3: representación de las trayectorias y el robot

% Ya tenemos todos los cálculos hechos y almacenados en las
% correspondientes matrices, procedemos a representar la trayectoria
% seguida por el end deffector y el movimiento del robot.

% Primero representamos la trayectoria seguida por el end deffector.

% Pero antes de nada eliminaremos la trayectoria del
% end-deffector de simulaciones previas si es que existe y el punto de la
% previa simulación.


% De simulaciones previas del problema inverso y directo:            
   delete(app.trayecEndDef)          
   delete(app.point)

% De simulaciones previas de trayectorias
   delete(app.points)
   delete(app.trayecTotEndDef)


% El número de trayectorias intermedias será igual al número de puntos 
% introducidos. Estas trayectorias intermedias se representaran 
% conjuntamente para poder representar la trayectoria total realizada desde 
% la posición original del robot hasta el último punto introducido.
% Alamcenamos el conjunto de trayectorias en el vector trayecTotEndDef.
% Inicializamos trayecTotEndDef:

app.trayecTotEndDef = linspace(0,0,app.r);

% Para ir representando las trayectorias y almacenarlas en trayecTotEndDef
% creamos un ciclo for:

for i = 1:app.r
    app.trayecTotEndDef(i) = plot3(X_path(i,:),Y_path(i,:),Z_path(i,:),'Color',[rand rand rand], 'LineWidth',2);
    hold on
end
% Cada trayectoria se representará en un color aleatorio

% Definimos los límites del plot

xlim([-400,1000]);
ylim([-700,700]);
zlim([0,1000]);


% Los puntos introducidos se representaran con una esfera y se almacenaran
% en points. Inicializamos points:

app.points = linspace(0,0,app.r+1);

for i=1:app.r-1
 app.points(i) = plot_sphere([xx(i+1),yy(i+1),zz(i+1)], 30, 'y');
end
% El punto final lo represento de rojo
app.points(app.r) = plot_sphere([xx(app.r+1),yy(app.r+1),zz(app.r+1)], 30, 'r');
% Represento el punto de origen con una esfera verde:
app.points(app.r+1) = plot_sphere([xx(1),yy(1),zz(1)], 30, 'g'); 

% Una vez representada la trayectoria total seguida por el end deffector
% procedemos a representra el robot en sus sucesivas posiciones

% Para ello, al igual que hemos hecho con la trayectoria seguida por el 
% end-deffector emplearemos un doble bucle for. El bucle exterior para el
% movimiento del robot a lo largo de cada trayectoria intermedia y el
% interior para representar el robot en los 20 puntos de precisión que forman
% cada trayectoria intermedia


for i=1:app.r
    for j = 1:20
    scara.plot([Th1_path(i,j) Th2_path(i,j) d3_path(i,j) Th4_path(i,j)], ...
        'scale',0.5,'perspective','jointdiam',2,'jaxes','shadow');
    end
end


% Guardo la posición final del robot, el valor final de mis variables, para
% que en la siguiente simulación esa sea la posición inicial del robot.

app.pos_in = [Th1_path(app.r,20) Th2_path(app.r,20) d3_path(app.r,20) Th4_path(app.r,20)];
app.l = app.l+1;
end


        end

        % Button pushed function: RepetirDir
        function RepetirDirPushed(app, event)
% Esta función repite la simulación realizada en el caso del problema directo

% Antes de nada se comprobará que los valores introducidos no por el 
% usuario no han sido modificados. En el caso de haber sido, aparecerá un 
% mensaje de error y no se repetirá la simulación. Lo que sí que se puede
% modificar es la velocidad de simulación.

if app.Theta1.Value ~= app.VarDirRep(1) || app.Theta2.Value ~= app.VarDirRep(2) || app.d3.Value ~= app.VarDirRep(3) || app.Theta4.Value ~= app.VarDirRep(4)
   warndlg('Alguno de los datos ha sido modificado por lo que no es posible repetir la simulación')
else


% Las variables del robot tienen que estar en radianes:
th1=app.VarDirRep(1)*pi/180; % los ángulos tienen que estar en radianes
th2=app.VarDirRep(2)*pi/180;
th4=app.VarDirRep(4)*pi/180;
d3 = app.VarDirRep(3);

L1 = Link('d',app.d1,'a',app.a1,'alpha',0,'qlim',[-105*pi/180,105*pi/180]); %R
L2 = Link('d',0,'a',app.a2,'alpha',pi,'qlim',[-157,5*pi/180,157,5*pi/180]);  %R
L3 = Link('theta',0,'a',0,'alpha',0,'qlim',[0,210]); %P
L4 = Link('d',0,'a',0,'alpha',0); %R no tiene limitación de giro

% Armamos el robot con la función SerialLink que crea la cadena cinemática.

scara = SerialLink([L1,L2,L3,L4],'name','Scara');

% Que la velocidad de representación cambie implica que se emplearán
% menos puntos de repetición. Como en las repeticiones de las simulaciones
% se puede ajustar la velocidad hay que volver a determinar el número de
% puntos de precisión que se van a emplear para representar el robot en su
% movimiento.

difTh1 = abs(app.PosRep(1)-th1); % en radianes
difTh2 = abs(app.PosRep(2)-th2);
difD3 = abs(app.PosRep(3)-d3); % en milimetros

difTh1 = difTh1*180/pi;
difTh2 = difTh2*180/pi;

dif = [difTh1, difTh2, difD3];

rep = round(max(dif)/(2*app.vel),0); % tiene que ser un número entero

Th1_path = linspace(app.PosRep(1),th1,rep);
Th2_path = linspace(app.PosRep(2),th2,rep);
d3_path  = linspace(app.PosRep(3),d3,rep);
Th4_path = linspace(app.PosRep(4),th4,rep);


P_path = zeros(rep,3);

for i = 1:1:rep % ciclo for que va desde 1 hasta 20 con incrementos de 1
    P_path(i,1) = app.a1*cos(Th1_path(i)) + app.a2*cos(Th1_path(i)+Th2_path(i));%x
    P_path(i,2) = app.a1*sin(Th1_path(i)) + app.a2*sin(Th1_path(i)+Th2_path(i));%y
    P_path(i,3) = app.d1 - d3_path(i); %z
end


delete(app.trayecEndDef)     

app.trayecEndDef = plot3(P_path(:,1),P_path(:,2),P_path(:,3),'Color',[1 0 0], 'LineWidth',2);


hold on 

xlim([-400,1000]);
ylim([-700,700]);
zlim([0,1000]);


for i=1:rep
    
    scara.plot([Th1_path(i) Th2_path(i) d3_path(i) Th4_path(i)], ...
        'scale',0.5,'perspective','jointdiam',2,'jaxes','shadow');
end

end

        end

        % Button pushed function: RepetirInv
        function RepetirInvButtonPushed(app, event)
% Esta función repite la simulación realizada en el caso del problema
% inverso

% Antes de nada se comprobará que los valores introducidos no por el 
% usuario no han sido modificados. En el caso de haber sido, aparecerá un 
% mensaje de error y no se repetirá la simulación.

x = app.PosInvRep(1);
y = app.PosInvRep(2);
z = app.PosInvRep(3);
theta4 = app.PosInvRep(4);

if app.X.Value ~= x || app.Y.Value ~= y || app.Z.Value ~= z || app.Orientacion.Value ~= theta4
   warndlg('Alguno de los datos ha sido modificado por lo que no es posible repetir la simulación')
else

L1 = Link('d',app.d1,'a',app.a1,'alpha',0,'qlim',[-105*pi/180,105*pi/180]); %R
L2 = Link('d',0,'a',app.a2,'alpha',pi,'qlim',[-157,5*pi/180,157,5*pi/180]);  %R
L3 = Link('theta',0,'a',0,'alpha',0,'qlim',[0,210]); %P 
L4 = Link('d',0,'a',0,'alpha',0); %R no tiene limitación de giro

% Armamos el robot con la función SerialLink que crea la cadena cinemática.

scara = SerialLink([L1,L2,L3,L4],'name','Scara');

x0 = app.a1*cos(app.PosRep(1)) + app.a2*cos(app.PosRep(1)+app.PosRep(2));
y0 = app.a1*sin(app.PosRep(1)) + app.a2*sin(app.PosRep(1)+app.PosRep(2));
z0 = app.d1 - app.PosRep(3);

% Que la velocidad de representación cambie implica que se emplearán
% menos puntos de repetición. Como en las repeticiones de las simulaciones
% se puede ajustar la velocidad hay que volver a determinar el número de
% puntos de precisión que se van a emplear para representar el robot en su
% movimiento.


difTh1 = abs(app.PosRep(1)-app.VarInvRep(1))*180/pi; % en grados
difTh2 = abs(app.PosRep(2)-app.VarInvRep(2))*180/pi;
difD3 = abs(app.PosRep(3)-app.VarInvRep(3)); % en milimetros

dif = [difTh1, difTh2, difD3];

if app.selecc == 0
    warndlg('Seleccione la velocidad de simulación', 'Aviso','modal')
else

rep = round(max(dif)/(2*app.vel),0); % tiene que ser un número entero

% Creamos los vectores asociados a cada variable usando la función 
% linspace(x1,x2,N). Esta función nos proporciona un vector fila con N 
% puntos igualmente espaciados que van desde X1 a X2.

Th1_path = linspace(app.PosRep(1),app.VarInvRep(1),rep);
Th2_path = linspace(app.PosRep(2),app.VarInvRep(2),rep);
d3_path  = linspace(app.PosRep(3),app.VarInvRep(3),rep);
Th4_path = linspace(app.PosRep(4),app.VarInvRep(4),rep);


P_path = zeros(rep,3);

for i = 1:1:rep % ciclo for que va desde 1 hasta 20 con incrementos de 1
    P_path(i,1) = app.a1*cos(Th1_path(i)) + app.a2*cos(Th1_path(i)+Th2_path(i));%x
    P_path(i,2) = app.a1*sin(Th1_path(i)) + app.a2*sin(Th1_path(i)+Th2_path(i));%y
    P_path(i,3) = app.d1 - d3_path(i); %z
end


   delete(app.trayecEndDef)  
   delete(app.point)

app.trayecEndDef = plot3(P_path(:,1),P_path(:,2),P_path(:,3),'Color',[1 0 0], 'LineWidth',2);

hold on

% definimos los límites del plot

xlim([-400,1000]);
ylim([-700,700]);
zlim([0,1000]);


   app.point = [plot_sphere([app.PosInvRep(1),app.PosInvRep(2),app.PosInvRep(3)], 30, 'y'), plot_sphere([x0,y0,z0],30,'g')];

for i=1:rep
    
    scara.plot([Th1_path(i) Th2_path(i) d3_path(i) Th4_path(i)], ...
        'scale',0.5,'perspective','jointdiam',2,'jaxes','shadow');
end

  
end
end
        end

        % Button pushed function: RepSim
        function RepSimButtonPushed(app, event)
% Función para repetir la simulación de la trayectoria

% Antes de nada se comprobará que no se ha modificado el número de puntos
% ni las coordenadas ni orientación de los mismos.
p = 0;

if app.r == app.np % comprobación nº de puntos
    if app.r == 2
            if app.PointsRep(2,1) ~= app.x1.Value || app.PointsRep(2,2) ~= app.y1.Value || app.PointsRep(2,3) ~= app.z1.Value || app.PointsRep(2,4) ~= app.Orientacion1.Value
                warndlg ('Se ha modificado el punto 1. No se puede repetir la simulación')
            elseif app.PointsRep(3,1) ~= app.x2.Value || app.PointsRep(3,2) ~= app.y2.Value || app.PointsRep(3,3) ~= app.z2.Value || app.PointsRep(3,4) ~= app.Orientacion2.Value
                 warndlg ('Se ha modificado el punto 2. No se puede repetir la simulación')
            else 
                p = 1; % Si p es 1 se repite la simulación
            end
    elseif app.r == 3
            if app.PointsRep(2,1) ~= app.x1.Value || app.PointsRep(2,2) ~= app.y1.Value || app.PointsRep(2,3) ~= app.z1.Value || app.PointsRep(2,4) ~= app.Orientacion1.Value
                warndlg ('Se ha modificado el punto 1. No se puede repetir la simulación')
            elseif app.PointsRep(3,1) ~= app.x2.Value || app.PointsRep(3,2) ~= app.y2.Value || app.PointsRep(3,3) ~= app.z2.Value || app.PointsRep(3,4) ~= app.Orientacion2.Value
                 warndlg ('Se ha modificado el punto 2. No se puede repetir la simulación')
            elseif app.PointsRep(4,1) ~= app.x3.Value || app.PointsRep(4,2) ~= app.y3.Value || app.PointsRep(4,3) ~= app.z3.Value || app.PointsRep(4,4) ~= app.Orientacion3.Value
                 warndlg ('Se ha modificado el punto 3. No se puede repetir la simulación')
            else 
                p = 1; % Si p es 1 se repite la simulación
            end
    elseif app.r == 4
            if app.PointsRep(2,1) ~= app.x1.Value || app.PointsRep(2,2) ~= app.y1.Value || app.PointsRep(2,3) ~= app.z1.Value || app.PointsRep(2,4) ~= app.Orientacion1.Value
                warndlg ('Se ha modificado el punto 1. No se puede repetir la simulación')
            elseif app.PointsRep(3,1) ~= app.x2.Value || app.PointsRep(3,2) ~= app.y2.Value || app.PointsRep(3,3) ~= app.z2.Value || app.PointsRep(3,4) ~= app.Orientacion2.Value
                 warndlg ('Se ha modificado el punto 2. No se puede repetir la simulación')
            elseif app.PointsRep(4,1) ~= app.x3.Value || app.PointsRep(4,2) ~= app.y3.Value || app.PointsRep(4,3) ~= app.z3.Value || app.PointsRep(4,4) ~= app.Orientacion3.Value
                 warndlg ('Se ha modificado el punto 3. No se puede repetir la simulación')
            elseif app.PointsRep(5,1) ~= app.x4.Value || app.PointsRep(5,2) ~= app.y4.Value || app.PointsRep(5,3) ~= app.z4.Value || app.PointsRep(5,4) ~= app.Orientacion4.Value
                 warndlg ('Se ha modificado el punto 4. No se puede repetir la simulación')
            else 
                p = 1; % Si p es 1 se repite la simulación
            end
    elseif app.r == 5
            if app.PointsRep(2,1) ~= app.x1.Value || app.PointsRep(2,2) ~= app.y1.Value || app.PointsRep(2,3) ~= app.z1.Value || app.PointsRep(2,4) ~= app.Orientacion1.Value
                warndlg ('Se ha modificado el punto 1. No se puede repetir la simulación')
            elseif app.PointsRep(3,1) ~= app.x2.Value || app.PointsRep(3,2) ~= app.y2.Value || app.PointsRep(3,3) ~= app.z2.Value || app.PointsRep(3,4) ~= app.Orientacion2.Value
                 warndlg ('Se ha modificado el punto 2. No se puede repetir la simulación')
            elseif app.PointsRep(4,1) ~= app.x3.Value || app.PointsRep(4,2) ~= app.y3.Value || app.PointsRep(4,3) ~= app.z3.Value || app.PointsRep(4,4) ~= app.Orientacion3.Value
                 warndlg ('Se ha modificado el punto 3. No se puede repetir la simulación')
            elseif app.PointsRep(5,1) ~= app.x4.Value || app.PointsRep(5,2) ~= app.y4.Value || app.PointsRep(5,3) ~= app.z4.Value || app.PointsRep(5,4) ~= app.Orientacion4.Value
                 warndlg ('Se ha modificado el punto 4. No se puede repetir la simulación')
            elseif app.PointsRep(6,1) ~= app.x5.Value || app.PointsRep(6,2) ~= app.y5.Value || app.PointsRep(6,3) ~= app.z5.Value || app.PointsRep(6,4) ~= app.Orientacion5.Value
                 warndlg ('Se ha modificado el punto 5. No se puede repetir la simulación')
            else 
                p = 1; % Si p es 1 se repite la simulación
            end
    end

else 
    warndlg('Se ha modificado el número de puntos. No es posible repetir la simulación', 'Aviso', 'modal');
end


% Sólo se repetirá la simulación cuando no hayan cambiado los puntos, es
% decir, cuando p = 1;


if p == 1

L1 = Link('d',app.d1,'a',app.a1,'alpha',0,'qlim',[-105*pi/180,105*pi/180]); %R
L2 = Link('d',0,'a',app.a2,'alpha',pi,'qlim',[-157,5*pi/180,157,5*pi/180]);  %R
L3 = Link('theta',0,'a',0,'alpha',0,'qlim',[0,210]); %P 
L4 = Link('d',0,'a',0,'alpha',0); %R no tiene limitación de giro

scara = SerialLink([L1,L2,L3,L4],'name','Scara');

Th1_path = zeros(app.r,20);
Th2_path = zeros(app.r,20);
d3_path = zeros(app.r,20);
Th4_path = zeros(app.r,20);


for i = 1: app.r
    Th1_path(i,:) = linspace(app.Th1Trayect(i),app.Th1Trayect(i+1),20); 
    Th2_path(i,:) = linspace(app.Th2Trayect(i),app.Th2Trayect(i+1),20);
    d3_path(i,:) = linspace(app.d3Trayect(i),app.d3Trayect(i+1),20);
    Th4_path(i,:) = linspace(app.Th4Trayect(i),app.Th4Trayect(i+1),20);
end

X_path = zeros(app.r,20);
Y_path = zeros(app.r,20);
Z_path = zeros(app.r,20);

for i = 1:app.r
    for j = 1:20
        X_path(i,j) = app.a1*cos(Th1_path(i,j)) + app.a2*cos(Th1_path(i,j)+Th2_path(i,j));%x

        Y_path(i,j) = app.a1*sin(Th1_path(i,j)) + app.a2*sin(Th1_path(i,j)+Th2_path(i,j));%y
            
        Z_path(i,j) = app.d1 - d3_path(i,j); %z
    end
    
end

   delete(app.points)
   delete(app.trayecTotEndDef)


app.trayecTotEndDef = linspace(0,0,app.r);


for i = 1:app.r
    app.trayecTotEndDef(i) = plot3(X_path(i,:),Y_path(i,:),Z_path(i,:),'Color',[rand rand rand], 'LineWidth',2);
    hold on
end
% Cada trayectoria se representará en un color aleatorio

% Definimos los límites del plot

xlim([-400,1000]);
ylim([-700,700]);
zlim([0,1000]);


% Los puntos introducidos se representaran con una esfera y se almacenaran
% en points. Inicializamos points:

app.points = linspace(0,0,app.r+1);

for i=1:app.r-1
 app.points(i) = plot_sphere([app.PointsRep(i+1,1),app.PointsRep(i+1,2),app.PointsRep(i+1,3)], 30, 'y');
end

% Punto final lo represento de rojo

app.points(app.r) = plot_sphere([app.PointsRep(app.r+1,1),app.PointsRep(app.r+1,2),app.PointsRep(app.r+1,3)], 30, 'r');
  
% Represento el punto de origen con una esfera verde:
app.points(app.r+1) = plot_sphere([app.PointsRep(1,1),app.PointsRep(1,2),app.PointsRep(1,3)], 30, 'g'); 


for i=1:app.r
    for j = 1:20
    scara.plot([Th1_path(i,j) Th2_path(i,j) d3_path(i,j) Th4_path(i,j)], ...
        'scale',0.5,'perspective','jointdiam',2,'jaxes','shadow');
    end
end

end

        end

        % Value changed function: Velocidad
        function VelocidadValueChanged(app, event)
            app.vel = app.Velocidad.Value;
            
         % Función que determina la velocidad de simulación 
         %    - Si value = 1 -> Velocidad = x1
         %    - Si value = 2 -> Velocidad = x2
         %    - Si value = 3 -> Velocidad = x3
         %    - Si value = 5 -> Velociddad = x4
         % Si no se selecciona ninguna opción no se llevará a cabo la
         % simulación
         if app.vel > 0
             app.selecc = 1;
         end
            
        end

        % Value changing function: NumPtos
        function NumPtosValueChanging(app, event)

            app.np = event.Value;
            % Inicializo los vectores que almacenan las variables
            % articulares correspondientes a los puntos introducidos
            app.Th1Trayect = zeros(app.np+1);
            app.Th2Trayect = zeros(app.np+1);
            app.d3Trayect = zeros(app.np+1);
            app.Th4Trayect = zeros(app.np+1);
           if app.np == 2
                app.p1 = 0; % Si se realiza más de una simulación 
                % hay que volver a comprobar los puntos desde 0
                app.p2 = 0;
                app.c = 0;
                app.x3.Visible = 'off';
                app.y3.Visible = 'off';
                app.z3.Visible = 'off'
                app.x4.Visible = 'off';
                app.y4.Visible = 'off';
                app.z4.Visible = 'off';
                app.x5.Visible = 'off';
                app.y5.Visible = 'off';
                app.z5.Visible = 'off';
                app.Punto3Label.Visible = 'off';
                app.Punto4Label.Visible = 'off';
                app.Punto5Label.Visible = 'off';
                app.pto3.Visible = 'off';
                app.pto4.Visible = 'off';
                app.pto5.Visible = 'off';
                app.X3Label.Visible = 'off';
                app.Y3Label.Visible = 'off';
                app.Z3Label.Visible = 'off';
                app.X4Label.Visible = 'off';
                app.Y4Label.Visible = 'off';
                app.Z4Label.Visible = 'off';
                app.X5Label.Visible = 'off';
                app.Y5Label.Visible = 'off';
                app.Z5Label.Visible = 'off';
                app.Orientacion3.Visible = 'off';
                app.Orientacion4.Visible = 'off';
                app.Orientacion5.Visible = 'off';
                app.OrLabel3.Visible = 'off';
                app.OrLabel4.Visible = 'off';
                app.OrLabel5.Visible = 'off';

           elseif app.np == 3
                app.p1 = 0;
                app.p2 = 0;
                app.p3 = 0;
                app.c = 0;
                app.x3.Visible = 'on';
                app.y3.Visible = 'on';
                app.z3.Visible = 'on';
                app.x4.Visible = 'off';
                app.y4.Visible = 'off';
                app.z4.Visible = 'off';
                app.x5.Visible = 'off';
                app.y5.Visible = 'off';
                app.z5.Visible = 'off';
                app.Punto3Label.Visible = 'on';
                app.Punto4Label.Visible = 'off';
                app.Punto5Label.Visible = 'off';
                app.pto3.Visible = 'on';
                app.pto4.Visible = 'off';
                app.pto5.Visible = 'off';
                app.X3Label.Visible = 'on';
                app.Y3Label.Visible = 'on';
                app.Z3Label.Visible = 'on';
                app.X4Label.Visible = 'off';
                app.Y4Label.Visible = 'off';
                app.Z4Label.Visible = 'off';
                app.X5Label.Visible = 'off';
                app.Y5Label.Visible = 'off';
                app.Z5Label.Visible = 'off';
                app.Orientacion3.Visible = 'on';
                app.Orientacion4.Visible = 'off';
                app.Orientacion5.Visible = 'off';
                app.OrLabel3.Visible = 'on';
                app.OrLabel4.Visible = 'off';
                app.OrLabel5.Visible = 'off';

           elseif app.np == 4
                app.p1 = 0;
                app.p2 = 0;
                app.p3 = 0;
                app.p4 = 0;
                app.c = 0;
                app.x3.Visible = 'on';
                app.y3.Visible = 'on';
                app.z3.Visible = 'on';
                app.x4.Visible = 'on';
                app.y4.Visible = 'on';
                app.z4.Visible = 'on';
                app.x5.Visible = 'off';
                app.y5.Visible = 'off';
                app.z5.Visible = 'off';
                app.Punto3Label.Visible = 'on';
                app.Punto4Label.Visible = 'on';
                app.Punto5Label.Visible = 'off';
                app.pto3.Visible = 'on';
                app.pto4.Visible = 'on';
                app.pto5.Visible = 'off';
                app.X3Label.Visible = 'on';
                app.Y3Label.Visible = 'on';
                app.Z3Label.Visible = 'on';
                app.X4Label.Visible = 'on';
                app.Y4Label.Visible = 'on';
                app.Z4Label.Visible = 'on';
                app.X5Label.Visible = 'off';
                app.Y5Label.Visible = 'off';
                app.Z5Label.Visible = 'off';
                app.Orientacion3.Visible = 'on';
                app.Orientacion4.Visible = 'on';
                app.Orientacion5.Visible = 'off';
                app.OrLabel3.Visible = 'on';
                app.OrLabel4.Visible = 'on';
                app.OrLabel5.Visible = 'off';


           elseif app.np == 5
                app.p1 = 0;
                app.p2 = 0;
                app.p3 = 0;
                app.p4 = 0;
                app.p5 = 0;
                app.c = 0;
                app.x3.Visible = 'on';
                app.y3.Visible = 'on';
                app.z3.Visible = 'on';
                app.x4.Visible = 'on';
                app.y4.Visible = 'on';
                app.z4.Visible = 'on';
                app.x5.Visible = 'on';
                app.y5.Visible = 'on';
                app.z5.Visible = 'on';
                app.Punto3Label.Visible = 'on';
                app.Punto4Label.Visible = 'on';
                app.Punto5Label.Visible = 'on';
                app.pto3.Visible = 'on';
                app.pto4.Visible = 'on';
                app.pto5.Visible = 'on';
                app.X3Label.Visible = 'on';
                app.Y3Label.Visible = 'on';
                app.Z3Label.Visible = 'on';
                app.X4Label.Visible = 'on';
                app.Y4Label.Visible = 'on';
                app.Z4Label.Visible = 'on';
                app.X5Label.Visible = 'on';
                app.Y5Label.Visible = 'on';
                app.Z5Label.Visible = 'on';
                app.Orientacion3.Visible = 'on';
                app.Orientacion4.Visible = 'on';
                app.Orientacion5.Visible = 'on';
                app.OrLabel3.Visible = 'on';
                app.OrLabel4.Visible = 'on';
                app.OrLabel5.Visible = 'on';


           end
        end

        % Drop down opening function: SeleccinmatrizDropDown
        function SeleccinmatrizDropDownOpening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProblemadirectoeinversoTab = uitab(app.TabGroup);
            app.ProblemadirectoeinversoTab.Title = 'Problema directo e inverso';
            app.ARTICULACIONESLabel = uilabel(app.ProblemadirectoeinversoTab);
            app.ARTICULACIONESLabel.Text = 'ARTICULACIONES';
            app.ENDDEFFECTORLabel = uilabel(app.ProblemadirectoeinversoTab);
            app.ENDDEFFECTORLabel.Text = 'END DEFFECTOR';
            app.DirBut = uibutton(app.ProblemadirectoeinversoTab, 'push');
            app.DirBut.ButtonPushedFcn = createCallbackFcn(app, @DirButButtonPushed, true);
            app.DirBut.Text = 'Directo';
            app.InvBut = uibutton(app.ProblemadirectoeinversoTab, 'push');
            app.InvBut.ButtonPushedFcn = createCallbackFcn(app, @InvButButtonPushed, true);
            app.InvBut.Text = 'Inverso';
            app.InfoInv = uiimage(app.ProblemadirectoeinversoTab);
            app.InfoInv.ImageClickedFcn = createCallbackFcn(app, @InfoInvImageClicked, true);
            app.InfoInv.ImageSource = 'info.png';
            app.Theta1EditFieldLabel = uilabel(app.ProblemadirectoeinversoTab);
            app.Theta1EditFieldLabel.Text = 'Theta1';
            app.Theta1 = uieditfield(app.ProblemadirectoeinversoTab, 'numeric');
            app.Theta2Label = uilabel(app.ProblemadirectoeinversoTab);
            app.Theta2Label.Text = 'Theta2';
            app.Theta2 = uieditfield(app.ProblemadirectoeinversoTab, 'numeric');
            app.Theta4EditFieldLabel = uilabel(app.ProblemadirectoeinversoTab);
            app.Theta4EditFieldLabel.Text = 'Theta4';
            app.Theta4 = uieditfield(app.ProblemadirectoeinversoTab, 'numeric');
            app.XEditFieldLabel = uilabel(app.ProblemadirectoeinversoTab);
            app.XEditFieldLabel.Text = 'X';
            app.X = uieditfield(app.ProblemadirectoeinversoTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.ProblemadirectoeinversoTab);
            app.YEditFieldLabel.Text = 'Y';
            app.Y = uieditfield(app.ProblemadirectoeinversoTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.ProblemadirectoeinversoTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.Z = uieditfield(app.ProblemadirectoeinversoTab, 'numeric');
            app.d3EditFieldLabel = uilabel(app.ProblemadirectoeinversoTab);
            app.d3EditFieldLabel.Text = 'd3';
            app.d3 = uieditfield(app.ProblemadirectoeinversoTab, 'numeric');
            app.InfoDir = uiimage(app.ProblemadirectoeinversoTab);
            app.InfoDir.ImageClickedFcn = createCallbackFcn(app, @InfoDirImageClicked, true);
            app.InfoDir.ImageSource = 'info.png';
            app.OrientacinLabel = uilabel(app.ProblemadirectoeinversoTab);
            app.OrientacinLabel.Text = {'Orientación '; ''};
            app.Orientacion = uieditfield(app.ProblemadirectoeinversoTab, 'numeric');
            app.RepetirDir = uibutton(app.ProblemadirectoeinversoTab, 'push');
            app.RepetirDir.ButtonPushedFcn = createCallbackFcn(app, @RepetirDirPushed, true);
            app.RepetirDir.Text = 'Repetir simulación';
            app.RepetirInv = uibutton(app.ProblemadirectoeinversoTab, 'push');
            app.RepetirInv.ButtonPushedFcn = createCallbackFcn(app, @RepetirInvButtonPushed, true);
            app.RepetirInv.Text = 'Repetir simulación';
            app.VelocidaddesimulacinDropDownLabel = uilabel(app.ProblemadirectoeinversoTab);
            app.VelocidaddesimulacinDropDownLabel.Text = 'Velocidad de simulación';
            app.Velocidad = uidropdown(app.ProblemadirectoeinversoTab);
            app.Velocidad.Items = {'Seleccione', 'x 1', 'x 2', 'x 3', 'x 4'};
            app.Velocidad.ItemsData = [0 1 2 3 4];
            app.Velocidad.ValueChangedFcn = createCallbackFcn(app, @VelocidadValueChanged, true);
            app.Velocidad.Value = 0;
            app.MatricesdetransformacinTab = uitab(app.TabGroup);
            app.MatricesdetransformacinTab.Title = 'Matrices de transformación';
            app.MatrizdetransformacinhomogeneaPanel = uipanel(app.MatricesdetransformacinTab);
            app.MatrizdetransformacinhomogeneaPanel.Title = 'Matriz de transformación homogenea';
            app.a11 = uieditfield(app.MatrizdetransformacinhomogeneaPanel, 'numeric');
            app.a12 = uieditfield(app.MatrizdetransformacinhomogeneaPanel, 'numeric');
            app.a13 = uieditfield(app.MatrizdetransformacinhomogeneaPanel, 'numeric');
            app.a14 = uieditfield(app.MatrizdetransformacinhomogeneaPanel, 'numeric');
            app.a21 = uieditfield(app.MatrizdetransformacinhomogeneaPanel, 'numeric');
            app.a22 = uieditfield(app.MatrizdetransformacinhomogeneaPanel, 'numeric');
            app.a23 = uieditfield(app.MatrizdetransformacinhomogeneaPanel, 'numeric');
            app.a24 = uieditfield(app.MatrizdetransformacinhomogeneaPanel, 'numeric');
            app.a31 = uieditfield(app.MatrizdetransformacinhomogeneaPanel, 'numeric');
            app.a32 = uieditfield(app.MatrizdetransformacinhomogeneaPanel, 'numeric');
            app.a33 = uieditfield(app.MatrizdetransformacinhomogeneaPanel, 'numeric');
            app.a34 = uieditfield(app.MatrizdetransformacinhomogeneaPanel, 'numeric');
            app.a41 = uieditfield(app.MatrizdetransformacinhomogeneaPanel, 'numeric');
            app.a42 = uieditfield(app.MatrizdetransformacinhomogeneaPanel, 'numeric');
            app.a43 = uieditfield(app.MatrizdetransformacinhomogeneaPanel, 'numeric');
            app.a44 = uieditfield(app.MatrizdetransformacinhomogeneaPanel, 'numeric');
            app.SeleccinmatrizDropDownLabel = uilabel(app.MatricesdetransformacinTab);
            app.SeleccinmatrizDropDownLabel.Text = 'Selección matriz';
            app.SeleccinmatrizDropDown = uidropdown(app.MatricesdetransformacinTab);
            app.SeleccinmatrizDropDown.Items = {'Seleccione', 'T01', 'T12', 'T23', 'T34', 'T04'};
            app.SeleccinmatrizDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', ''};
            app.SeleccinmatrizDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SeleccinmatrizDropDownOpening, true);
            app.SeleccinmatrizDropDown.ValueChangedFcn = createCallbackFcn(app, @SeleccinmatrizDropDownValueChanged, true);
            app.SeleccinmatrizDropDown.Value = '0';
            app.ParmetrosdeDHPanel = uipanel(app.MatricesdetransformacinTab);
            app.ParmetrosdeDHPanel.Title = 'Parámetros de DH';
            app.thetaEditField_2Label = uilabel(app.ParmetrosdeDHPanel);
            app.thetaEditField_2Label.Text = 'theta';
            app.AlphaDH = uieditfield(app.ParmetrosdeDHPanel, 'numeric');
            app.ThetaDH = uieditfield(app.ParmetrosdeDHPanel, 'numeric');
            app.alphaEditField_2Label = uilabel(app.ParmetrosdeDHPanel);
            app.alphaEditField_2Label.Text = 'alpha';
            app.aEditField_2Label = uilabel(app.ParmetrosdeDHPanel);
            app.aEditField_2Label.Text = 'a';
            app.aDH = uieditfield(app.ParmetrosdeDHPanel, 'numeric');
            app.dDH = uieditfield(app.ParmetrosdeDHPanel, 'numeric');
            app.dEditField_2Label = uilabel(app.ParmetrosdeDHPanel);
            app.dEditField_2Label.Text = 'd';
            app.ClculodetrayectoriasTab = uitab(app.TabGroup);
            app.ClculodetrayectoriasTab.Title = 'Cálculo de trayectorias';
            app.Punto1Label = uilabel(app.ClculodetrayectoriasTab);
            app.Punto1Label.Text = 'Punto 1';
            app.Punto2Label = uilabel(app.ClculodetrayectoriasTab);
            app.Punto2Label.Text = 'Punto 2';
            app.Punto3Label = uilabel(app.ClculodetrayectoriasTab);
            app.Punto3Label.Text = 'Punto 3';
            app.Punto4Label = uilabel(app.ClculodetrayectoriasTab);
            app.Punto4Label.Text = 'Punto 4';
            app.Punto5Label = uilabel(app.ClculodetrayectoriasTab);
            app.Punto5Label.Text = 'Punto 5';
            app.x1 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.x2 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.x3 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.x4 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.x5 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.y1 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.z1 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.y2 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.z2 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.y3 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.z3 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.y4 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.z4 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.y5 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.z5 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.pto1 = uibutton(app.ClculodetrayectoriasTab, 'push');
            app.pto1.ButtonPushedFcn = createCallbackFcn(app, @pto1ButtonPushed, true);
            app.pto1.Text = 'Añadir Pto 1';
            app.pto2 = uibutton(app.ClculodetrayectoriasTab, 'push');
            app.pto2.ButtonPushedFcn = createCallbackFcn(app, @pto2ButtonPushed, true);
            app.pto2.Text = 'Añadir Pto 2';
            app.pto3 = uibutton(app.ClculodetrayectoriasTab, 'push');
            app.pto3.ButtonPushedFcn = createCallbackFcn(app, @pto3ButtonPushed, true);
            app.pto3.Text = 'Añadir Pto 3';
            app.pto4 = uibutton(app.ClculodetrayectoriasTab, 'push');
            app.pto4.ButtonPushedFcn = createCallbackFcn(app, @pto4ButtonPushed, true);
            app.pto4.Text = 'Añadir Pto 4';
            app.pto5 = uibutton(app.ClculodetrayectoriasTab, 'push');
            app.pto5.ButtonPushedFcn = createCallbackFcn(app, @pto5ButtonPushed, true);
            app.pto5.Text = 'Añadir Pto 5';
            app.trayect = uibutton(app.ClculodetrayectoriasTab, 'push');
            app.trayect.ButtonPushedFcn = createCallbackFcn(app, @trayectButtonPushed, true);
            app.trayect.Text = 'Representar trayectoria';
            app.NmerodepuntosSpinnerLabel = uilabel(app.ClculodetrayectoriasTab);
            app.NmerodepuntosSpinnerLabel.Text = 'Número de puntos';
            app.NumPtos = uispinner(app.ClculodetrayectoriasTab);
            app.NumPtos.ValueChangingFcn = createCallbackFcn(app, @NumPtosValueChanging, true);
            app.NumPtos.Limits = [2 5];
            app.NumPtos.Value = 2;
            app.X1Label = uilabel(app.ClculodetrayectoriasTab);
            app.X1Label.Text = 'X1 ';
            app.Y1Label = uilabel(app.ClculodetrayectoriasTab);
            app.Y1Label.Text = 'Y1 ';
            app.Z1Label = uilabel(app.ClculodetrayectoriasTab);
            app.Z1Label.Text = 'Z1 ';
            app.X2Label = uilabel(app.ClculodetrayectoriasTab);
            app.X2Label.Text = 'X2 ';
            app.Y2Label = uilabel(app.ClculodetrayectoriasTab);
            app.Y2Label.Text = 'Y2';
            app.Z2Label = uilabel(app.ClculodetrayectoriasTab);
            app.Z2Label.Text = 'Z2';
            app.X3Label = uilabel(app.ClculodetrayectoriasTab);
            app.X3Label.Text = 'X3 ';
            app.Y3Label = uilabel(app.ClculodetrayectoriasTab);
            app.Y3Label.Text = 'Y3';
            app.Z3Label = uilabel(app.ClculodetrayectoriasTab);
            app.Z3Label.Text = 'Z3';
            app.X4Label = uilabel(app.ClculodetrayectoriasTab);
            app.X4Label.Text = 'X4';
            app.Y4Label = uilabel(app.ClculodetrayectoriasTab);
            app.Y4Label.Text = 'Y4';
            app.Z4Label = uilabel(app.ClculodetrayectoriasTab);
            app.Z4Label.Text = 'Z4';
            app.X5Label = uilabel(app.ClculodetrayectoriasTab);
            app.X5Label.Text = 'X5 ';
            app.Y5Label = uilabel(app.ClculodetrayectoriasTab);
            app.Y5Label.Text = 'Y5';
            app.Z5Label = uilabel(app.ClculodetrayectoriasTab);
            app.Z5Label.Text = 'Z5';
            app.Orientacion1 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.OrLabel1 = uilabel(app.ClculodetrayectoriasTab);
            app.OrLabel1.Text = 'Orientación';
            app.Orientacion2 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.OrLabel2 = uilabel(app.ClculodetrayectoriasTab);
            app.OrLabel2.Text = 'Orientación';
            app.Orientacion3 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.OrLabel3 = uilabel(app.ClculodetrayectoriasTab);
            app.OrLabel3.Text = 'Orientación';
            app.Orientacion4 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.OrLabel4 = uilabel(app.ClculodetrayectoriasTab);
            app.OrLabel4.Text = 'Orientación';
            app.Orientacion5 = uieditfield(app.ClculodetrayectoriasTab, 'numeric');
            app.OrLabel5 = uilabel(app.ClculodetrayectoriasTab);
            app.OrLabel5.Text = 'Orientación';
            app.RepSim = uibutton(app.ClculodetrayectoriasTab, 'push');
            app.RepSim.ButtonPushedFcn = createCallbackFcn(app, @RepSimButtonPushed, true);
            app.RepSim.Text = 'Repetir simulación';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Definitivo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end